classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        utensils     matlab.ui.control.Image
        STARTButton  matlab.ui.control.Button
        square       matlab.ui.control.Image
        background   matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: UIFigure, utensils
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key,'leftarrow')
                app.square.Position(1) = app.square.Position(1) -10
            end
            if strcmp(key,'rightarrow')
                app.square.Position(1) = app.square.Position(1) +10
            end
            if strcmp(key,'uparrow')
                app.square.Position(2) = app.square.Position(2) +10
            end
            if strcmp(key,'downarrow')
                app.square.Position(2) = app.square.Position(2) -10
            end
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            value = app.START.Button;
            if value==1
                %%command to run
            else
                %%command to stop
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.background = uiimage(app.UIFigure);
            app.background.ImageSource = fullfile(pathToMLAPP, 'LabelledTrashcan.png');
            app.square = uiimage(app.UIFigure);
            app.square.ImageSource = fullfile(pathToMLAPP, 'square.png');
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.utensils = uiimage(app.UIFigure);
            app.utensils.ImageClickedFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.utensils.ImageSource = fullfile(pathToMLAPP, 'Utensils.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end